classdef Lab10GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        PhotoAxes                      matlab.ui.control.UIAxes
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        GridLayout8                    matlab.ui.container.GridLayout
        FileNameEditField              matlab.ui.control.EditField
        FileNameLabel                  matlab.ui.control.Label
        GridLayout7                    matlab.ui.container.GridLayout
        SampleRateHzEditField          matlab.ui.control.NumericEditField
        SampleRateHzEditFieldLabel     matlab.ui.control.Label
        WavelengthnmEditField          matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel     matlab.ui.control.Label
        MaxLEDCurrentmAEditField       matlab.ui.control.NumericEditField
        MaxLEDCurrentmAEditFieldLabel  matlab.ui.control.Label
        AnalogInputDropDownLabel       matlab.ui.control.Label
        AnalogInputDropDown            matlab.ui.control.DropDown
        AnalogOutputDropDown           matlab.ui.control.DropDown
        AnalogOutputDropDownLabel      matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        StopButton                     matlab.ui.control.Button
        ClearButton                    matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        %DAQ Variables
        d
        dev
        data

        %Channel Variables
        AIChannel
        AOChannel
        
        %Data Variables
        RLoad
        pd_v
        pd_i
        led_v                               %measured voltage across LED resistor
        R_lambda                            %responsivity derived from Figure 1 in lab manual

        %UI Variables
        lamda
        sampleRate
        fileName
        led_i

        %Constants
        Imax = 0.030;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUpFunc(app)
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
        end

        % Value changed function: MaxLEDCurrentmAEditField
        function LEDCurrentChanged(app, event)
            app.led_i = app.MaxLEDCurrentmAEditField.Value;
            
        end

        % Value changed function: WavelengthnmEditField
        function WavelengthChanged(app, event)
            app.lamda = app.WavelengthnmEditField.Value;
            
        end

        % Value changed function: SampleRateHzEditField
        function SampleRateChanged(app, event)
            app.sampleRate = app.SampleRateHzEditField.Value;    
        end

        % Value changed function: AnalogOutputDropDown
        function AOChannelChanged(app, event)
            app.AOChannel = app.AnalogOutputDropDown.Value;
        end

        % Value changed function: AnalogInputDropDown
        function AIChannelChanged(app, event)
             app.AIChannel = app.AnalogInputDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.StartButton = uibutton(app.GridLayout3, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ClearButton = uibutton(app.GridLayout3, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.StopButton = uibutton(app.GridLayout3, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.AnalogOutputDropDownLabel = uilabel(app.GridLayout7);
            app.AnalogOutputDropDownLabel.Text = 'Analog Output';
            app.AnalogOutputDropDown = uidropdown(app.GridLayout7);
            app.AnalogOutputDropDown.Items = {'ao0', 'ao1'};
            app.AnalogOutputDropDown.ValueChangedFcn = createCallbackFcn(app, @AOChannelChanged, true);
            app.AnalogOutputDropDown.Value = 'ao0';
            app.AnalogInputDropDown = uidropdown(app.GridLayout7);
            app.AnalogInputDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.AnalogInputDropDown.ValueChangedFcn = createCallbackFcn(app, @AIChannelChanged, true);
            app.AnalogInputDropDown.Value = 'ai0';
            app.AnalogInputDropDownLabel = uilabel(app.GridLayout7);
            app.AnalogInputDropDownLabel.Text = 'Analog Input';
            app.MaxLEDCurrentmAEditFieldLabel = uilabel(app.GridLayout7);
            app.MaxLEDCurrentmAEditFieldLabel.Text = 'Max LED Current [mA]';
            app.MaxLEDCurrentmAEditField = uieditfield(app.GridLayout7, 'numeric');
            app.MaxLEDCurrentmAEditField.Limits = [0 30];
            app.MaxLEDCurrentmAEditField.ValueChangedFcn = createCallbackFcn(app, @LEDCurrentChanged, true);
            app.MaxLEDCurrentmAEditField.Value = 30;
            app.WavelengthnmEditFieldLabel = uilabel(app.GridLayout7);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength [nm]';
            app.WavelengthnmEditField = uieditfield(app.GridLayout7, 'numeric');
            app.WavelengthnmEditField.Limits = [0 1000000000];
            app.WavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthChanged, true);
            app.SampleRateHzEditFieldLabel = uilabel(app.GridLayout7);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate [Hz]';
            app.SampleRateHzEditField = uieditfield(app.GridLayout7, 'numeric');
            app.SampleRateHzEditField.Limits = [0 10000];
            app.SampleRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateChanged, true);
            app.SampleRateHzEditField.Value = 10;
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.FileNameLabel = uilabel(app.GridLayout8);
            app.FileNameLabel.Text = 'File Name:';
            app.FileNameEditField = uieditfield(app.GridLayout8, 'text');
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.PhotoAxes = uiaxes(app.GridLayout5);
            title(app.PhotoAxes, 'Photodiode Current vsLED Current')
            xlabel(app.PhotoAxes, 'LED Current [mA]')
            ylabel(app.PhotoAxes, 'Photodiode Current [mA]')
            zlabel(app.PhotoAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab10GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartUpFunc)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end