classdef Lab12GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        IntensityPlot                   matlab.ui.control.UIAxes
        TESTCheckBox                    matlab.ui.control.CheckBox
        AquisitionEditField             matlab.ui.control.EditField
        AquisitionEditFieldLabel        matlab.ui.control.Label
        STARTButton                     matlab.ui.control.Button
        MaxLEDCurrentEditField          matlab.ui.control.NumericEditField
        MaxLEDCurrentEditFieldLabel     matlab.ui.control.Label
        LEDCurrentChDropDownLabel       matlab.ui.control.Label
        LEDCurrentChDropDown            matlab.ui.control.DropDown
        LEDWavelengthDropDown           matlab.ui.control.DropDown
        LEDWavelengthDropDownLabel      matlab.ui.control.Label
        LEDResistorValueEditField       matlab.ui.control.NumericEditField
        LEDResistorValueEditFieldLabel  matlab.ui.control.Label
        SampleDelayEditField            matlab.ui.control.NumericEditField
        SampleDelayEditFieldLabel       matlab.ui.control.Label
        CurrentStepSizeEditField        matlab.ui.control.NumericEditField
        CurrentStepSizeEditFieldLabel   matlab.ui.control.Label
        CounterPFIInputChDropDown       matlab.ui.control.DropDown
        CounterPFIInputChDropDownLabel  matlab.ui.control.Label
        VoltageRampAOChDropDown         matlab.ui.control.DropDown
        VoltageRampAOChDropDownLabel    matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        %Daq variables
        d
        dev

        %Channel Variables
        counterCh               %channel for the digital counter input
        rampCh                  %channel for analog output voltage ramp
        ledCh                   %LED voltage channel to be converted to current
        counterCh_i             %default ch
        rampCh_i                %default ch
        ledCh_i                 %default ch

        %UI Parameters
        led_Imax                %limits the maximum range of the current ramp
        dI                      %sets the change in current for each step
        dt                      %sets the time between aquisitions
        led_R                   %sets the value of the current limiting resistor
        lamda                   %sets the wavelength for frequency conversion

        %Program Variables
        i = 1                   %iterator
        testFlag=0              %
        led_In                  %variable for the current n
        led_Vout=zeros(1,2047)  %blank array to be filled with ramp values
        scanData                %variable to be used for daq read
        data
        resp = 2.3              %variable for responsivity based on wavelength [kHz*(uW/cm^2)]
        freq                    %variable for frequency of the counter
        led_samples             %Set to number of samples
        Intensity               %Intensity calculated from calibration curve of light snesor [uW/cm^2]
        ledData
        intensityData
        frequencyData
        timestamps

        %Program Constants
        Vdrop = 2
        fDark                   %variable for fDark
        VariableNames = {'Current','Intensity'}          %Names for table vairables
        VariableUnits = {'mA','mW/cm^2'}                 %Units for vairable output

    end
    
    methods (Access = private)
        
        function generateAO(app)
            app.led_In = 0;                                 %start with no current
            app.led_samples = app.led_Imax/app.dI;          %number of sampels to be written out
            Vmax = (app.led_Imax * app.led_R);              %set the maximum voltage out independant of led voltage drop
            dV = Vmax/app.led_samples;                      %calculate the change in voltage at each step
            Vi = app.Vdrop;                                 %set Vi to the voltage drop across the LED
            app.led_Vout(1) = 0;                            %
            for j=2:app.led_samples+1                         %repeat until max current reached
                app.led_Vout(j) = Vi;                       %populate vout array with analog output values
                Vi = Vi + dV;                               %add dV to next voltage
            end
        end
        
        function InitializeVariables(app)
            %Fills all the application varaibels based on UI and defaults
            app.counterCh_i = app.CounterPFIInputChDropDown.Value;          %Set default channel values
            app.rampCh_i = app.VoltageRampAOChDropDown.Value;
            app.ledCh_i = app.LEDCurrentChDropDown.Value;
            app.counterCh = app.counterCh_i;                                %set channel values to default
            app.rampCh = app.rampCh_i;                                      
            app.ledCh = app.ledCh_i;
            MaxLEDCurrentEditFieldValueChanged(app, event);                 %set all default user interface parameters
            CurrentStepSizeEditFieldValueChanged(app, event);
            SampleDelayEditFieldValueChanged(app, event);
            LEDResistorValueEditFieldValueChanged(app, event);
            LEDWavelengthDropDownValueChanged(app, event);
            devices = daqlist("ni");                                        %creats list of devides
            app.dev = devices(1,"DeviceID");                                %saves device name of first daq found in app.dev
            app.d = daq('ni');                                              %create data aquisition object, always need this line*** passes to all function that do data aquisition
        end
        
        function AdminMeasurement(app)
            %Runs the voltage ramp with delay for measurment between
            %points, returns the output to zero volts after finished
            
            addclock(app.d,app.counterCh,"ctr0");
            app.ch0 = addinput(app.d,app.dev.DeviceID,app.ledCh,'Voltage');           %generate led voltage in channel
            app.ch1 = addinput(app.d,app.dev.DeviceID,app.counterCh,'ctr0','Frequency');     %generate frequency in channel
            app.ch1.ActiveEdge = "Falling";                                           %configure trigger edge
            app.ch2 = addoutput(app.d,app.dev.DeviceID,app.rampCh,'Voltage');         %generate ramp out channel                  
            app.ch0.TerminalConfig='Differential';                                    %Set to differential input

            j=1;
            try
                if app.testFlag == 1
                    app.d.Rate=10;
                    app.d.ScansAvailableFcnCount = app.d.Rate*1;
                    app.d.ScansAvailableFcn = @(scan,evt) plotTest(app,scan,evt);           %Executes once callback is met
                    start(app.d,"continuous");
                    while app.testFlag == 1]
                    end
                    stop(app.d);            
                    removechannel(app.d, 1:length(app.d.Channels));
                else
                    while j <= app.LED_samples+1
                        sampleN = app.LED_Vout(1:j);                                %sets voltage array index to the variable sampleN
                        write(app.d,sampleN);                                       %Writes data to analog output
                        pause(app.dt);                                              %wait for circuit to catch up
                        app.scanData = read(app.d);                                 %Read data
                        j = j+1;
                    end
                end
                write(app.d,0);
            catch
                app.AquisitionEditField.Value='Aquisition Error, Try Again';
                removechannel(app.d);
            end
        end

        function PlotData(app,scan,~)
            app.ledData = app.scanData(:,1);
            app.frequencyData = app.scanData(:,2);
            app.intensityData = mean(FreqToIntensity(app));
            plot(app.UIFigure,app.ledData,app.frequencyData);
            app.newDataTable = table(app.ledData,app.intensityData);
        end
        
        
        function results = FreqToIntensity(app)
            results = (app.frequencyData - app.fDark)/app.resp;
        end
        
        function plotTest(app,scan,~)
            [app.data, app.timestamps,~]=read(scan, scan.ScansAvailableFcnCount,"OutputFormat","Matrix");   
            plot(app.IntensityPlot,app.timestamps,app.data);                        
            hold("on",app.IntensityPlot);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            generateAO(app);                                                %populate AO voltage array by reference
            AdminMeasurement(app);                                          %runs the measurement and data collection
            PlotData(app,scan);                                             %plots the data on GUI Figure
        end

        % Code that executes after component creation
        function startupFcn(app)
            InitializeVariables(app);
        end

        % Value changed function: CounterPFIInputChDropDown
        function CounterPFIInputChDropDownValueChanged(app, event)
            app.counterCh = app.CounterPFIInputChDropDown.Value;
        end

        % Value changed function: MaxLEDCurrentEditField
        function MaxLEDCurrentEditFieldValueChanged(app, event)
            app.led_Imax = app.MaxLEDCurrentEditField.Value/1000;
        end

        % Value changed function: CurrentStepSizeEditField
        function CurrentStepSizeEditFieldValueChanged(app, event)
            app.dI = app.CurrentStepSizeEditField.Value/1000;
        end

        % Value changed function: SampleDelayEditField
        function SampleDelayEditFieldValueChanged(app, event)
            app.dt = app.SampleDelayEditField.Value;           
        end

        % Value changed function: LEDResistorValueEditField
        function LEDResistorValueEditFieldValueChanged(app, event)
            app.led_R = app.LEDResistorValueEditField.Value;
        end

        % Value changed function: LEDWavelengthDropDown
        function LEDWavelengthDropDownValueChanged(app, event)
            app.lamda = app.LEDWavelengthDropDown.Value/1000000000;
        end

        % Value changed function: VoltageRampAOChDropDown
        function VoltageRampAOChDropDownValueChanged(app, event)
            app.rampCh = app.VoltageRampAOChDropDown.Value;
        end

        % Value changed function: LEDCurrentChDropDown
        function LEDCurrentChDropDownValueChanged(app, event)
            app.ledCh = app.LEDCurrentChDropDown.Value;
        end

        % Value changed function: TESTCheckBox
        function TESTCheckBoxValueChanged(app, event)
            value = app.TESTCheckBox.Value;
            if app.testflag == 1
                app.testflag = 0;
            else
                app.testflag = 1;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {293, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.IntensityPlot = uiaxes(app.RightPanel);
            title(app.IntensityPlot, 'Title')
            xlabel(app.IntensityPlot, 'X')
            ylabel(app.IntensityPlot, 'Y')
            zlabel(app.IntensityPlot, 'Z')
            app.VoltageRampAOChDropDownLabel = uilabel(app.UIFigure);
            app.VoltageRampAOChDropDownLabel.Text = 'Voltage Ramp AO Ch';
            app.VoltageRampAOChDropDown = uidropdown(app.UIFigure);
            app.VoltageRampAOChDropDown.Items = {'Analog Output 0', 'Analog Output 1'};
            app.VoltageRampAOChDropDown.ItemsData = {'ao0', 'ao1'};
            app.VoltageRampAOChDropDown.ValueChangedFcn = createCallbackFcn(app, @VoltageRampAOChDropDownValueChanged, true);
            app.VoltageRampAOChDropDown.Tooltip = {'Sets the channel for the analog output voltage ramp'};
            app.VoltageRampAOChDropDown.Value = 'ao1';
            app.CounterPFIInputChDropDownLabel = uilabel(app.UIFigure);
            app.CounterPFIInputChDropDownLabel.Text = 'Counter PFI Input Ch';
            app.CounterPFIInputChDropDown = uidropdown(app.UIFigure);
            app.CounterPFIInputChDropDown.Items = {'PFI0', 'PFI1', 'PFI2', 'PFI3', 'PFI4', 'PFI5', 'PFI6', 'PFI7'};
            app.CounterPFIInputChDropDown.ItemsData = {'Port0/Line0', 'Port0/Line1', 'Port0/Line2', 'Port0/Line3', 'Port0/Line4', 'Port0/Line5', 'Port0/Line6', 'Port0/Line7'};
            app.CounterPFIInputChDropDown.ValueChangedFcn = createCallbackFcn(app, @CounterPFIInputChDropDownValueChanged, true);
            app.CounterPFIInputChDropDown.Tooltip = {'Sets the channel for the frequency counter'};
            app.CounterPFIInputChDropDown.Value = 'Port0/Line0';
            app.CurrentStepSizeEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentStepSizeEditFieldLabel.Text = 'Current Step Size';
            app.CurrentStepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentStepSizeEditField.Limits = [0 Inf];
            app.CurrentStepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentStepSizeEditFieldValueChanged, true);
            app.CurrentStepSizeEditField.Tooltip = {'Sets the delta I for the current ramp [mA]'};
            app.CurrentStepSizeEditField.Value = 0.1;
            app.SampleDelayEditFieldLabel = uilabel(app.UIFigure);
            app.SampleDelayEditFieldLabel.Text = 'Sample Delay';
            app.SampleDelayEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleDelayEditField.Limits = [0.0001 10];
            app.SampleDelayEditField.ValueChangedFcn = createCallbackFcn(app, @SampleDelayEditFieldValueChanged, true);
            app.SampleDelayEditField.Tooltip = {'Sets the time in seconds between consecutive analog input measurements [s]'};
            app.SampleDelayEditField.Value = 1;
            app.LEDResistorValueEditFieldLabel = uilabel(app.UIFigure);
            app.LEDResistorValueEditFieldLabel.Text = 'LED Resistor Value';
            app.LEDResistorValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.LEDResistorValueEditField.ValueChangedFcn = createCallbackFcn(app, @LEDResistorValueEditFieldValueChanged, true);
            app.LEDResistorValueEditField.Tooltip = {'Change the value of the LED current limiting resistor for accurate calculation of current ramp [ohm]'};
            app.LEDResistorValueEditField.Value = 100;
            app.LEDWavelengthDropDownLabel = uilabel(app.UIFigure);
            app.LEDWavelengthDropDownLabel.Text = 'LED Wavelength';
            app.LEDWavelengthDropDown = uidropdown(app.UIFigure);
            app.LEDWavelengthDropDown.Items = {'520 [Green]', '630 [Red]', '450 [Blue]', '585 [Yellow]'};
            app.LEDWavelengthDropDown.ItemsData = [520 630 450 585];
            app.LEDWavelengthDropDown.ValueChangedFcn = createCallbackFcn(app, @LEDWavelengthDropDownValueChanged, true);
            app.LEDWavelengthDropDown.Tooltip = {'Select a wavelength to set responsivity of photodiode [nm]'};
            app.LEDWavelengthDropDown.Value = 520;
            app.LEDCurrentChDropDown = uidropdown(app.UIFigure);
            app.LEDCurrentChDropDown.Items = {'Differential Analog Input 0', 'Differential Analog Input 1', 'Differential Analog Input 2', 'Differential Analog Input 3', 'Differential Analog Input 4', 'Differential Analog Input 5', 'Differential Analog Input 6', 'Differential Analog Input 7'};
            app.LEDCurrentChDropDown.ItemsData = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.LEDCurrentChDropDown.ValueChangedFcn = createCallbackFcn(app, @LEDCurrentChDropDownValueChanged, true);
            app.LEDCurrentChDropDown.Tooltip = {'Channel for LED current'};
            app.LEDCurrentChDropDown.Value = 'ai0';
            app.LEDCurrentChDropDownLabel = uilabel(app.UIFigure);
            app.LEDCurrentChDropDownLabel.Text = 'LED Current Ch';
            app.MaxLEDCurrentEditFieldLabel = uilabel(app.UIFigure);
            app.MaxLEDCurrentEditFieldLabel.Text = 'Max LED Current';
            app.MaxLEDCurrentEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxLEDCurrentEditField.Limits = [0 30];
            app.MaxLEDCurrentEditField.ValueChangedFcn = createCallbackFcn(app, @MaxLEDCurrentEditFieldValueChanged, true);
            app.MaxLEDCurrentEditField.Tooltip = {'Sets the max current for the voltage ramp [mA]'};
            app.MaxLEDCurrentEditField.Value = 3;
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.AquisitionEditFieldLabel = uilabel(app.UIFigure);
            app.AquisitionEditFieldLabel.Tooltip = {'Current status of aquisition'};
            app.AquisitionEditFieldLabel.Text = 'Aquisition';
            app.AquisitionEditField = uieditfield(app.UIFigure, 'text');
            app.TESTCheckBox = uicheckbox(app.UIFigure);
            app.TESTCheckBox.ValueChangedFcn = createCallbackFcn(app, @TESTCheckBoxValueChanged, true);
            app.TESTCheckBox.Tooltip = {'Test connection and counter'};
            app.TESTCheckBox.Text = 'TEST';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab12GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end